classdef DataApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CaptureImageButton            matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes4                       matlab.ui.control.UIAxes
        MalariaTypesButtonGroup       matlab.ui.container.ButtonGroup
        GametocyteCheckBox            matlab.ui.control.CheckBox
        SchizontCheckBox              matlab.ui.control.CheckBox
        TrophozoiteCheckBox           matlab.ui.control.CheckBox
        RingCheckBox                  matlab.ui.control.CheckBox
        NoneCheckBox                  matlab.ui.control.CheckBox
        EnterButton                   matlab.ui.control.Button
        EnterSlideNameEditFieldLabel  matlab.ui.control.Label
        EnterSlideNameEditField       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        cam;
        x_url;
        y_url;% Description
        x;
        y;
        RGB;
        counter=0;
        g_counter=1;
        malaria_path;
        filename;
        filenamex;
        filenamey;
    end
    
    methods (Access = private)
        function [malaria_path abcd] = Malaria_type(app)
                    t = erase(string(datestr(datetime('now','TimeZone','local','Format','dMMMyHHmmss'))),["-"," ",":"]);
                    abcd=1;
                        
                    if app.NoneCheckBox.Value==1;
                        message = sprintf('Select Malria Types!');
                        opts = struct('WindowStyle','non-modal','Interpreter','tex');
                        errordlg(message,'Warning', opts);
                        malaria_path='';
                        abcd=0;
                    else
                        x= strcat(num2str(app.GametocyteCheckBox.Value),...
                            num2str(app.SchizontCheckBox.Value),...
                            num2str(app.TrophozoiteCheckBox.Value),...
                            num2str(app.RingCheckBox.Value));
                            malaria_path=strcat('.\Images\',app.EnterSlideNameEditField.Value,'\Malaria_',...
                                app.EnterSlideNameEditField.Value,'_','',t,'_',x);      
                    end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.cam=webcam(2);
            app.x_url= 'http://192.168.43.1:8080/shot.jpg';
            app.y_url='http://192.168.43.7:8080/shot.jpg';
            app.counter=0;
            warning('off')
        end

        % Button pushed function: CaptureImageButton
        function CaptureImageButtonPushed(app, event)
            
              [app.malaria_path check] = Malaria_type(app);
              app.CaptureImageButton.Text='Processing....';
              app.CaptureImageButton.BackgroundColor=[0.9 0 0 ];
              pause(1);
              app.RGB= snapshot(app.cam);
              imshow(app.RGB,'Parent',app.UIAxes4)
              app.filename=strcat(app.malaria_path,'.tif');
              imwrite(app.RGB,app.filename);  
              app.x=imrotate(imread(app.x_url),90);
              app.y=imrotate(imread(app.y_url),90);
              imshow(app.x,'Parent',app.UIAxes2)
              imshow(app.y,'Parent',app.UIAxes3)
              app.filenamex=strcat(app.malaria_path,'.jpg');
              imwrite(app.x,app.filenamex);
              app.filenamey=strcat(app.malaria_path,'.jpeg');
              imwrite(app.y,app.filenamey);
              app.CaptureImageButton.BackgroundColor=[0.96 0.960 0.96];
              app.g_counter=app.g_counter+1;
              app.CaptureImageButton.Text='Capture Image';
              app.NoneCheckBox.Value=1;
              app.GametocyteCheckBox.Value=0;
              app.SchizontCheckBox.Value=0;
              app.RingCheckBox.Value=0;
              app.TrophozoiteCheckBox.Value=0;
        
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
                 mkdir(strcat('./Images/',app.EnterSlideNameEditField.Value));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CaptureImageButton = uibutton(app.UIFigure, 'push');
            app.CaptureImageButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureImageButtonPushed, true);
            app.CaptureImageButton.Text = 'Capture Image';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, '')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.MalariaTypesButtonGroup = uibuttongroup(app.UIFigure);
            app.MalariaTypesButtonGroup.Title = 'Malaria Types';
            app.GametocyteCheckBox = uicheckbox(app.MalariaTypesButtonGroup);
            app.GametocyteCheckBox.Text = 'Gametocyte';
            app.SchizontCheckBox = uicheckbox(app.MalariaTypesButtonGroup);
            app.SchizontCheckBox.Text = 'Schizont ';
            app.TrophozoiteCheckBox = uicheckbox(app.MalariaTypesButtonGroup);
            app.TrophozoiteCheckBox.Text = 'Trophozoite';
            app.RingCheckBox = uicheckbox(app.MalariaTypesButtonGroup);
            app.RingCheckBox.Text = 'Ring';
            app.NoneCheckBox = uicheckbox(app.MalariaTypesButtonGroup);
            app.NoneCheckBox.Text = 'None';
            app.NoneCheckBox.Value = true;
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.EnterSlideNameEditFieldLabel = uilabel(app.UIFigure);
            app.EnterSlideNameEditFieldLabel.Text = 'Enter Slide Name';
            app.EnterSlideNameEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end